classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ControlsPanel                matlab.ui.container.Panel
        DropDown                     matlab.ui.control.DropDown
        RefinedImageButton           matlab.ui.control.Button
        EdgeThresEditField           matlab.ui.control.NumericEditField
        EdgeThresLabel               matlab.ui.control.Label
        EdgeThresholdSlider          matlab.ui.control.Slider
        EdgeThresholdSliderLabel     matlab.ui.control.Label
        SensEditField                matlab.ui.control.NumericEditField
        SensLabel                    matlab.ui.control.Label
        ThresEditField               matlab.ui.control.NumericEditField
        ThresLabel                   matlab.ui.control.Label
        ThresholdSlider              matlab.ui.control.Slider
        SenstivitySlider             matlab.ui.control.Slider
        SenstivitySliderLabel        matlab.ui.control.Label
        ApplyThersholdButton         matlab.ui.control.StateButton
        IdentifyBoundaryButton       matlab.ui.control.StateButton
        CalibrationPanel             matlab.ui.container.Panel
        CalibrateButton              matlab.ui.control.Button
        PlotCalibrationLineButton    matlab.ui.control.StateButton
        PixelsEditField              matlab.ui.control.NumericEditField
        PixelsEditFieldLabel         matlab.ui.control.Label
        LengthpxEditField            matlab.ui.control.NumericEditField
        LengthpxEditFieldLabel       matlab.ui.control.Label
        LengthEditField              matlab.ui.control.NumericEditField
        ImageWidthLabel              matlab.ui.control.Label
        LoadPanel                    matlab.ui.container.Panel
        FileNameEditField            matlab.ui.control.EditField
        FileNameLabel                matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        BrowseButton                 matlab.ui.control.Button
        EditingPanel                 matlab.ui.container.Panel
        DeleteButton                 matlab.ui.control.Button
        EditButton                   matlab.ui.control.Button
        SelectDropletButton          matlab.ui.control.StateButton
        AddDropletButton             matlab.ui.control.Button
        DrawDropletButton            matlab.ui.control.StateButton
        ResultsPanel                 matlab.ui.container.Panel
        DropletLabelsButton          matlab.ui.control.StateButton
        AverageRadiusEditField       matlab.ui.control.NumericEditField
        AverageRadiusEditFieldLabel  matlab.ui.control.Label
        NoofCirclesEditField         matlab.ui.control.NumericEditField
        NoofCirclesEditFieldLabel    matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        ImportExportPanel            matlab.ui.container.Panel
        ExportDataButton             matlab.ui.control.Button
        ImportDataButton             matlab.ui.control.Button
        RangePanel                   matlab.ui.container.Panel
        LineLengthEditField          matlab.ui.control.NumericEditField
        LineLengthLabel              matlab.ui.control.Label
        RmaxEditField                matlab.ui.control.NumericEditField
        RmaxLabel                    matlab.ui.control.Label
        RminEditField                matlab.ui.control.NumericEditField
        RminLabel                    matlab.ui.control.Label
        FindLengthButton             matlab.ui.control.Button
        DrawLineButton               matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img
        thimg
        h
        centers
        radii
        drl
        clbr
        a
        m
        s
        cntr
        rds
        txt
        file = 0;
        path
        lpx = 1;
        objp = 'dark'
    end
    
    methods (Access = private)
        
        function showcircles(app)
            app.h = viscircles(app.UIAxes,app.centers,app.radii,'EdgeColor','b','DrawBackgroundCircle',0,'LineStyle','-.','LineWidth',1.5);
            close(gcf)
        end
        
        function showmeasurements(app)
            app.UITable.Data = app.lpx*app.radii;
            app.NoofCirclesEditField.Value = length(app.radii);
            if ~isempty(app.radii)
                app.AverageRadiusEditField.Value = mean(app.lpx*app.radii);
            else
                app.AverageRadiusEditField.Value = 0;
            end
            if app.DropletLabelsButton.Value == 1
                n = length(app.radii);
                label = string(1:n);
                x = app.centers(1:n);
                y = app.centers(n+1:2*n);
                dx = app.radii(1:n);
                dx = (4/5)*(dx.');
                delete(app.txt);
                app.txt = text(x+dx,y+dx,label,'Color','g','Parent',app.UIAxes);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [app.file,app.path] = uigetfile({'*.png;*.jpg','Images (*.png,*.jpg)';'*.*', 'All Files (*.*)'});
            if isequal(app.file,0)
                return
            end
            cla(app.UIAxes,"reset");
            [app.img,map] = imread(fullfile(app.path,app.file));
            app.FileNameEditField.Value = app.file;
            if isempty(map)
                app.img = rgb2gray(app.img);
            else
                app.img = ind2gray(app.img,map); 
            end
            app.thimg = app.img;
            imshow(app.img,'parent',app.UIAxes);
            app.centers(:) = [];
            app.radii(:) = [];
            showcircles(app)
            app.ApplyThersholdButton.Value = 0;
            app.IdentifyBoundaryButton.Value = 0;
            app.DrawLineButton.Value = 0;
            app.DrawDropletButton.Value = 0;
            app.SelectDropletButton.Value = 0;
            app.LengthEditField.Value = 0;
            app.PixelsEditField.Value = 0;
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ThresEditField.Value = changingValue;
            if app.ApplyThersholdButton.Value == 1
                if ~isempty(app.thimg)
                    if app.IdentifyBoundaryButton.Value==1
                        app.thimg = app.img > app.ThresEditField.Value;
                        [app.centers,app.radii] = imfindcircles(app.thimg,[round(app.RminEditField.Value/app.lpx) round(app.RmaxEditField.Value/app.lpx)],Sensitivity=app.SensEditField.Value,ObjectPolarity = app.objp,EdgeThreshold=app.EdgeThresEditField.Value);    %,Method='twostage'
                        imshow(app.thimg,'Parent',app.UIAxes);
                        showcircles(app)
                        showmeasurements(app)
                    else
                        app.thimg = app.img > app.ThresEditField.Value;
                        imshow(app.thimg,'Parent',app.UIAxes);
                    end
                else
                    app.ApplyThersholdButton.Value = 0;
                end
            end
        end

        % Value changed function: ThresEditField
        function ThresEditFieldValueChanged(app, event)
            value = app.ThresEditField.Value;
            if value<0
                app.ThresEditField.Value = 0;
            elseif value>255
                app.ThresEditField.Value = 255;
            end
            app.ThresholdSlider.Value = app.ThresEditField.Value;
            if app.ApplyThersholdButton.Value == 1
                if ~isempty(app.thimg)
                    if app.IdentifyBoundaryButton.Value==1
                        app.thimg = app.img > app.ThresEditField.Value;
                        [app.centers,app.radii] = imfindcircles(app.thimg,[round(app.RminEditField.Value/app.lpx) round(app.RmaxEditField.Value/app.lpx)],Sensitivity=app.SensEditField.Value,ObjectPolarity = app.objp,EdgeThreshold=app.EdgeThresEditField.Value);    %,Method='twostage'
                        imshow(app.thimg,'Parent',app.UIAxes);
                        showcircles(app)
                        showmeasurements(app)
                    else
                        app.thimg = app.img > app.ThresEditField.Value;
                        imshow(app.thimg,'Parent',app.UIAxes);
                    end
                else
                    app.ApplyThersholdButton.Value = 0;
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ApplyThersholdButton.Value = 0;
            app.IdentifyBoundaryButton.Value = 0;
            app.DrawLineButton.Value = 0;
            app.DrawDropletButton.Value = 0;
            app.SelectDropletButton.Value = 0;
            app.DropletLabelsButton.Value = 0;

            app.LineLengthEditField.Value = 0;
            app.LengthEditField.Value = 0;
            app.PixelsEditField.Value = 0;
            app.LengthpxEditField.Value = 1;
            app.lpx = 1;
            app.UITable.Data = '';
            app.centers = [];
            app.radii = [];
            app.NoofCirclesEditField.Value = 0;
            app.AverageRadiusEditField.Value = 0;
            app.thimg = [];
            imshow(app.thimg,'parent',app.UIAxes);
            cla(app.UIAxes,"reset")
            app.FileNameEditField.Value = '';
            app.file = 0;
        end

        % Value changing function: SenstivitySlider
        function SenstivitySliderValueChanging(app, event)
            changingValue = event.Value;
            app.SensEditField.Value = changingValue;
            if app.IdentifyBoundaryButton.Value == 1
                if ~isempty(app.thimg)
                    [app.centers,app.radii] = imfindcircles(app.thimg,[round(app.RminEditField.Value/app.lpx) round(app.RmaxEditField.Value/app.lpx)],Sensitivity=app.SensEditField.Value,ObjectPolarity = app.objp,EdgeThreshold=app.EdgeThresEditField.Value);    %,Method='twostage'
                    app.h.Visible = 0;
                    showcircles(app);
                    showmeasurements(app);
                else
                    app.IdentifyBoundaryButton.Value = 0;
                end
            end
        end

        % Value changed function: SensEditField
        function SensEditFieldValueChanged(app, event)
            value = app.SensEditField.Value;
            if value<0.8
                app.SensEditField.Value = 0.8;
            elseif value>1
                app.SensEditField.Value = 1;
            end
            app.SenstivitySlider.Value = app.SensEditField.Value;
            if app.IdentifyBoundaryButton.Value == 1
                if ~isempty(app.thimg)
                    [app.centers,app.radii] = imfindcircles(app.thimg,[round(app.RminEditField.Value/app.lpx) round(app.RmaxEditField.Value/app.lpx)],Sensitivity=app.SensEditField.Value,ObjectPolarity = app.objp,EdgeThreshold=app.EdgeThresEditField.Value);    %,Method='twostage'
                    app.h.Visible = 0;
                    showcircles(app);
                    showmeasurements(app);
                else
                    app.IdentifyBoundaryButton.Value = 0;
                end
            end
        end

        % Value changed function: IdentifyBoundaryButton
        function IdentifyBoundaryButtonValueChanged(app, event)
            value = app.IdentifyBoundaryButton.Value;
            if value==1
                if ~isempty(app.thimg)
                    [app.centers,app.radii] = imfindcircles(app.thimg,[round(app.RminEditField.Value/app.lpx) round(app.RmaxEditField.Value/app.lpx)],Sensitivity=app.SensEditField.Value,ObjectPolarity = app.objp,EdgeThreshold=app.EdgeThresEditField.Value);    %,Method='twostage'
                    showcircles(app)
                    showmeasurements(app);
                else
                    app.IdentifyBoundaryButton.Value = 0;
                end
                                
            elseif value == 0
                app.DrawLineButton.Value = 0;
                app.DrawDropletButton.Value = 0;
                app.SelectDropletButton.Value = 0;
                imshow(app.thimg,'parent',app.UIAxes);
                app.centers(:) = [];
                app.radii(:) = [];
                showcircles(app)
                showmeasurements(app)
            end
        end

        % Value changed function: ApplyThersholdButton
        function ApplyThersholdButtonValueChanged(app, event)
            value = app.ApplyThersholdButton.Value;
            app.DrawLineButton.Value = 0;
            app.DrawDropletButton.Value = 0;
            app.SelectDropletButton.Value = 0;
            if value ==1
                if ~isempty(app.thimg)
                    if app.IdentifyBoundaryButton.Value==1
                        app.thimg = app.img > app.ThresEditField.Value;
                        [app.centers,app.radii] = imfindcircles(app.thimg,[round(app.RminEditField.Value/app.lpx) round(app.RmaxEditField.Value/app.lpx)],Sensitivity=app.SensEditField.Value,ObjectPolarity = app.objp,EdgeThreshold=app.EdgeThresEditField.Value);    %,Method='twostage'
                        imshow(app.thimg,'Parent',app.UIAxes);
                        showcircles(app)
                        showmeasurements(app)
                    else
                        app.thimg = app.img > app.ThresEditField.Value;
                        imshow(app.thimg,'Parent',app.UIAxes);
                    end
                else
                    app.ApplyThersholdButton.Value = 0;
                end
            elseif value == 0
                if app.IdentifyBoundaryButton.Value==1
                    app.thimg = app.img;
                    imshow(app.thimg,'Parent',app.UIAxes);
                    showcircles(app)
                    showmeasurements(app)
                else
                    app.thimg = app.img;
                    imshow(app.thimg,'Parent',app.UIAxes);
                end
            end
        end

        % Value changed function: DrawLineButton
        function DrawLineButtonValueChanged(app, event)
            if ~isempty(app.thimg)
                value = app.DrawLineButton.Value;
                if value == 1
                    app.drl = drawline("Deletable",true,"Visible",1,"Parent",app.UIAxes);
                elseif value == 0
                    app.drl.Visible=0;
                end
            else
                app.DrawLineButton.Value = 0;
            end
        end

        % Value changed function: DrawDropletButton
        function DrawDropletButtonValueChanged(app, event)
            value = app.DrawDropletButton.Value;
            if value == 1
                if ~isempty(app.thimg)
                    delete(app.m);
                    app.SelectDropletButton.Value = 0;
                    app.h.Visible = 0;
                    showcircles(app);
                    app.a = drawcircle("Parent",app.UIAxes,'DrawingArea','unlimited','Color','g','FaceAlpha',0,'LineWidth',1.3,'MarkerSize',3);
                else
                    app.DrawDropletButton.Value = 0;
                end
            elseif value == 0
                delete(app.a);
            end
        end

        % Button pushed function: AddDropletButton
        function AddDropletButtonPushed(app, event)
            if app.DrawDropletButton.Value == 1
                app.h.Visible = 0;
                radius = app.a.Radius;
                center = app.a.Center;
                if radius ~= 0
                    app.centers = [app.centers;center];
                    app.radii = [app.radii;radius];
                end
                delete(app.a);
                app.DrawDropletButton.Value = 0;
                showmeasurements(app);
                showcircles(app);
            end
        end

        % Value changed function: SelectDropletButton
        function SelectDropletButtonValueChanged(app, event)
            if ~isempty(app.radii)
                if app.DrawDropletButton.Value == 0
                    value = app.SelectDropletButton.Value;
                    if value == 1
                        app.cntr = app.centers;
                        app.rds = app.radii;
                        app.h.Visible = 0;
                        showcircles(app);
    
                        app.s = drawpoint("Parent",app.UIAxes);
                        x = app.s.Position(1);
                        y = app.s.Position(2);
                        delete(app.s);
                           
                        n = length(app.radii);
                        xtemp = app.centers(1);
                        ytemp = app.centers(1+n);
                        temp = 1;
                        for i = 1:n
                            if sqrt((x-xtemp)^2+(y-ytemp)^2) - sqrt((x-app.centers(i))^2+(y-app.centers(i+n))^2) >= 0
                                xtemp = app.centers(i);
                                ytemp = app.centers(i+n);
                                temp = i;
                            end
                        end
                        ctemp = [app.centers(temp),app.centers(temp+n)];
                        rtemp = app.radii(temp);
                        if sqrt((x-xtemp)^2+(y-ytemp)^2)<=rtemp
                            app.cntr(temp,:) = [];
                            app.rds(temp) = [];
                            app.h.Visible = 0;
                            app.h = viscircles(app.UIAxes,app.cntr,app.rds,'EdgeColor','b','DrawBackgroundCircle',0,'LineStyle','-.','LineWidth',1.5);
                            close(gcf)
                            app.m = drawcircle("Parent",app.UIAxes,'DrawingArea','unlimited','Color',[0.66,0.81,1.00],'LineWidth',1.3,'FaceAlpha',0,'MarkerSize',3,"Center",ctemp,Radius=rtemp);
                        else
                            app.SelectDropletButton.Value = 0;
                        end
    
                    elseif value == 0
                        delete(app.s);
                        delete(app.m);
                        app.h.Visible = 0;
                        showcircles(app);
                        showmeasurements(app);
                    end
                elseif app.DrawDropletButton.Value == 1
                    app.SelectDropletButton.Value = 0;
                end
            elseif isempty(app.radii)
                app.SelectDropletButton.Value = 0;
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if app.SelectDropletButton.Value == 1
                app.centers = app.cntr;
                app.radii = app.rds;
                app.centers = [app.centers;app.m.Center];
                app.radii = [app.radii;app.m.Radius];
                delete(app.m);
                app.h.Visible = 0;
                showcircles(app);
                showmeasurements(app);
                app.SelectDropletButton.Value = 0;
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if app.SelectDropletButton.Value == 1
                delete(app.m);
                app.centers = app.cntr;
                app.radii = app.rds;
                app.h.Visible = 0;
                showcircles(app);
                showmeasurements(app);
                app.SelectDropletButton.Value = 0;
            end
        end

        % Value changed function: DropletLabelsButton
        function DropletLabelsButtonValueChanged(app, event)
            value = app.DropletLabelsButton.Value;
            if value == 1
                showmeasurements(app);
            elseif value == 0
                delete(app.txt)
            end
        end

        % Value changed function: EdgeThresEditField
        function EdgeThresEditFieldValueChanged(app, event)
            value = app.EdgeThresEditField.Value;
            if value<0
                app.EdgeThresEditField.Value = 0;
            elseif value>1
                app.EdgeThresEditField.Value = 1;
            end
            app.EdgeThresholdSlider.Value = app.EdgeThresEditField.Value;
            if app.IdentifyBoundaryButton.Value == 1
                if ~isempty(app.thimg)
                    [app.centers,app.radii] = imfindcircles(app.thimg,[round(app.RminEditField.Value/app.lpx) round(app.RmaxEditField.Value/app.lpx)],Sensitivity=app.SensEditField.Value,ObjectPolarity = app.objp,EdgeThreshold=app.EdgeThresEditField.Value);    %,Method='twostage'
                    app.h.Visible = 0;
                    showcircles(app);
                    showmeasurements(app);
                else
                    app.IdentifyBoundaryButton.Value = 0;
                end
            end
        end

        % Value changing function: EdgeThresholdSlider
        function EdgeThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EdgeThresEditField.Value = changingValue;
            if app.IdentifyBoundaryButton.Value == 1
                if ~isempty(app.thimg)
                    [app.centers,app.radii] = imfindcircles(app.thimg,[round(app.RminEditField.Value/app.lpx) round(app.RmaxEditField.Value/app.lpx)],Sensitivity=app.SensEditField.Value,ObjectPolarity = app.objp,EdgeThreshold=app.EdgeThresEditField.Value);    %,Method='twostage'
                    app.h.Visible = 0;
                    showcircles(app);
                    showmeasurements(app);
                else
                    app.IdentifyBoundaryButton.Value = 0;
                end
            end
        end

        % Button pushed function: RefinedImageButton
        function RefinedImageButtonPushed(app, event)
            if app.ApplyThersholdButton.Value == 1
                sel = strel('disk',round(0.8*app.RminEditField.Value/app.lpx),4);
                if app.IdentifyBoundaryButton.Value==1
                    app.thimg = imclose(imopen(app.thimg,sel),sel);
                    [app.centers,app.radii] = imfindcircles(app.thimg,[round(app.RminEditField.Value/app.lpx) round(app.RmaxEditField.Value/app.lpx)],Sensitivity=app.SensEditField.Value,ObjectPolarity = app.objp,EdgeThreshold=app.EdgeThresEditField.Value);    %,Method='twostage'
                    imshow(app.thimg,'Parent',app.UIAxes);
                    showcircles(app)
                    showmeasurements(app)
                else
                    app.thimg = imclose(imopen(app.thimg,sel),sel);
                    imshow(app.thimg,'Parent',app.UIAxes);
                end
            end
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [imp_file,imp_path] = uigetfile('*.mat','Import Data');
            if isequal(imp_file,0)
                return
            end
            b = load(fullfile(imp_path,imp_file));
            cla(app.UIAxes,"reset");

            app.centers = b.a1;
            app.radii = b.a2;
            app.img = b.a3;
            app.ThresEditField.Value = b.a4; 
            app.SensEditField.Value = b.a5;
            app.EdgeThresEditField.Value = b.a6;
            app.RminEditField.Value = b.a7;
            app.RmaxEditField.Value = b.a8;
            app.FileNameEditField.Value = b.a9;
            app.lpx = b.a10;
            app.objp = b.a11;

            %***************
            if size(app.objp,2) == 4
                app.DropDown.Value = "Black Circles";
            else
                app.DropDown.Value = "White Circles";
            end
            app.file = app.FileNameEditField.Value;
            app.ThresholdSlider.Value = app.ThresEditField.Value;
            app.SenstivitySlider.Value = app.SensEditField.Value;
            app.EdgeThresholdSlider.Value = app.EdgeThresEditField.Value;
            app.thimg = app.img;
            app.LengthpxEditField.Value = app.lpx;
            app.LengthEditField.Value = 0;
            app.PixelsEditField.Value = 0;

            app.ApplyThersholdButton.Value = 0;
            if isempty(app.radii)
                app.IdentifyBoundaryButton.Value = 0;
            else
                app.IdentifyBoundaryButton.Value = 1;
            end
            app.DropletLabelsButton.Value = 0;
            app.DrawDropletButton.Value = 0;
            app.SelectDropletButton.Value = 0;
            app.DrawLineButton.Value = 0;

            imshow(app.thimg,'parent',app.UIAxes);
            showcircles(app)
            showmeasurements(app)
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if isequal(app.file,0)
                return
            end
            [~,newfile] = fileparts(app.file);  
            fname = sprintf("%s_DataSet.mat",newfile);
            [filename,export_path] = uiputfile(fname,"Export Data");
            if isequal(export_path,0)
                return
            end
            a1 = app.centers;
            a2 = app.radii;
            a3 = app.img;
            a4 = app.ThresEditField.Value; 
            a5 = app.SensEditField.Value;
            a6 = app.EdgeThresEditField.Value;
            a7 = app.RminEditField.Value;
            a8 = app.RmaxEditField.Value;
            a9 = app.FileNameEditField.Value;
            a10 = app.lpx;
            a11 = app.objp;

            save(fullfile(export_path,filename),'a1','a2','a3','a4','a5','a6','a7','a8','a9','a10','a11')
        end

        % Button pushed function: FindLengthButton
        function FindLengthButtonPushed(app, event)
            if app.DrawLineButton.Value == 1
                pos = app.drl.Position;
                diffPos = diff(pos);
                lt = hypot(diffPos(1),diffPos(2));
                app.LineLengthEditField.Value = lt*app.lpx;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "Black Circles"
                app.objp = 'dark';
            else
                app.objp = 'bright';
            end
        end

        % Value changed function: PlotCalibrationLineButton
        function PlotCalibrationLineButtonValueChanged(app, event)
            if ~isempty(app.thimg)
                value = app.PlotCalibrationLineButton.Value;
                if value == 1
                    app.clbr = drawline("Deletable",true,"Visible",1,"Parent",app.UIAxes);
                elseif value == 0
                    app.clbr.Visible=0;
                end
            else
                app.PlotCalibrationLineButton.Value = 0;
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            if app.PlotCalibrationLineButton.Value == 1
                pos = app.clbr.Position;
                delete(app.clbr);
                diffPos = diff(pos);
                app.PixelsEditField.Value = hypot(diffPos(1),diffPos(2));
                if app.LengthEditField.Value>0
                    app.LengthpxEditField.Value = app.LengthEditField.Value/app.PixelsEditField.Value;
                else
                    app.LengthpxEditField.Value = 1;
                end
                app.lpx = app.LengthpxEditField.Value;
                app.PlotCalibrationLineButton.Value = 0;
                showmeasurements(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.RangePanel = uipanel(app.UIFigure);
            app.RangePanel.Title = 'Range';
            app.DrawLineButton = uibutton(app.RangePanel, 'state');
            app.DrawLineButton.ValueChangedFcn = createCallbackFcn(app, @DrawLineButtonValueChanged, true);
            app.DrawLineButton.Text = 'Draw Line';
            app.FindLengthButton = uibutton(app.RangePanel, 'push');
            app.FindLengthButton.ButtonPushedFcn = createCallbackFcn(app, @FindLengthButtonPushed, true);
            app.FindLengthButton.Text = 'Find Length';
            app.RminLabel = uilabel(app.RangePanel);
            app.RminLabel.Text = 'Rmin:';
            app.RminEditField = uieditfield(app.RangePanel, 'numeric');
            app.RminEditField.Value = 15;
            app.RmaxLabel = uilabel(app.RangePanel);
            app.RmaxLabel.Text = 'Rmax:';
            app.RmaxEditField = uieditfield(app.RangePanel, 'numeric');
            app.RmaxEditField.Value = 35;
            app.LineLengthLabel = uilabel(app.RangePanel);
            app.LineLengthLabel.Text = 'Line Length:';
            app.LineLengthEditField = uieditfield(app.RangePanel, 'numeric');
            app.LineLengthEditField.Editable = 'off';
            app.ImportExportPanel = uipanel(app.UIFigure);
            app.ImportExportPanel.Title = 'Import/Export';
            app.ImportDataButton = uibutton(app.ImportExportPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.ExportDataButton = uibutton(app.ImportExportPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.UITable = uitable(app.ResultsPanel);
            app.UITable.ColumnName = {'Radii of Droplets'};
            app.NoofCirclesEditFieldLabel = uilabel(app.ResultsPanel);
            app.NoofCirclesEditFieldLabel.Text = 'No. of Circles:';
            app.NoofCirclesEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.NoofCirclesEditField.Editable = 'off';
            app.AverageRadiusEditFieldLabel = uilabel(app.ResultsPanel);
            app.AverageRadiusEditFieldLabel.Text = 'Average Radius:';
            app.AverageRadiusEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.AverageRadiusEditField.Editable = 'off';
            app.DropletLabelsButton = uibutton(app.ResultsPanel, 'state');
            app.DropletLabelsButton.ValueChangedFcn = createCallbackFcn(app, @DropletLabelsButtonValueChanged, true);
            app.DropletLabelsButton.Text = 'Droplet Labels';
            app.EditingPanel = uipanel(app.UIFigure);
            app.EditingPanel.Title = 'Editing';
            app.DrawDropletButton = uibutton(app.EditingPanel, 'state');
            app.DrawDropletButton.ValueChangedFcn = createCallbackFcn(app, @DrawDropletButtonValueChanged, true);
            app.DrawDropletButton.Text = 'Draw Droplet';
            app.AddDropletButton = uibutton(app.EditingPanel, 'push');
            app.AddDropletButton.ButtonPushedFcn = createCallbackFcn(app, @AddDropletButtonPushed, true);
            app.AddDropletButton.Text = 'Add Droplet';
            app.SelectDropletButton = uibutton(app.EditingPanel, 'state');
            app.SelectDropletButton.ValueChangedFcn = createCallbackFcn(app, @SelectDropletButtonValueChanged, true);
            app.SelectDropletButton.Text = 'Select Droplet';
            app.EditButton = uibutton(app.EditingPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteButton = uibutton(app.EditingPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.LoadPanel = uipanel(app.UIFigure);
            app.LoadPanel.Title = 'Load';
            app.BrowseButton = uibutton(app.LoadPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ResetButton = uibutton(app.LoadPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FileNameLabel = uilabel(app.LoadPanel);
            app.FileNameLabel.Text = 'File Name :';
            app.FileNameEditField = uieditfield(app.LoadPanel, 'text');
            app.FileNameEditField.Editable = 'off';
            app.CalibrationPanel = uipanel(app.UIFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.ImageWidthLabel = uilabel(app.CalibrationPanel);
            app.ImageWidthLabel.Text = 'Length:';
            app.LengthEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.LengthpxEditFieldLabel = uilabel(app.CalibrationPanel);
            app.LengthpxEditFieldLabel.Text = 'Length/px:';
            app.LengthpxEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.LengthpxEditField.Editable = 'off';
            app.LengthpxEditField.Value = 1;
            app.PixelsEditFieldLabel = uilabel(app.CalibrationPanel);
            app.PixelsEditFieldLabel.Text = 'Pixels:';
            app.PixelsEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.PixelsEditField.Editable = 'off';
            app.PlotCalibrationLineButton = uibutton(app.CalibrationPanel, 'state');
            app.PlotCalibrationLineButton.ValueChangedFcn = createCallbackFcn(app, @PlotCalibrationLineButtonValueChanged, true);
            app.PlotCalibrationLineButton.Text = 'Plot Calibration Line';
            app.CalibrateButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.IdentifyBoundaryButton = uibutton(app.ControlsPanel, 'state');
            app.IdentifyBoundaryButton.ValueChangedFcn = createCallbackFcn(app, @IdentifyBoundaryButtonValueChanged, true);
            app.IdentifyBoundaryButton.Text = 'Identify Boundary';
            app.ApplyThersholdButton = uibutton(app.ControlsPanel, 'state');
            app.ApplyThersholdButton.ValueChangedFcn = createCallbackFcn(app, @ApplyThersholdButtonValueChanged, true);
            app.ApplyThersholdButton.Text = 'Apply Thershold';
            app.SenstivitySliderLabel = uilabel(app.ControlsPanel);
            app.SenstivitySliderLabel.Text = 'Senstivity';
            app.SenstivitySlider = uislider(app.ControlsPanel);
            app.SenstivitySlider.Limits = [0.8 1];
            app.SenstivitySlider.ValueChangingFcn = createCallbackFcn(app, @SenstivitySliderValueChanging, true);
            app.SenstivitySlider.Value = 0.95423542035814;
            app.ThresholdSlider = uislider(app.ControlsPanel);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 60;
            app.ThresLabel = uilabel(app.ControlsPanel);
            app.ThresLabel.Text = 'Thres:';
            app.ThresEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.ThresEditField.ValueChangedFcn = createCallbackFcn(app, @ThresEditFieldValueChanged, true);
            app.ThresEditField.Value = 60;
            app.SensLabel = uilabel(app.ControlsPanel);
            app.SensLabel.Text = 'Sens:';
            app.SensEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SensEditField.ValueChangedFcn = createCallbackFcn(app, @SensEditFieldValueChanged, true);
            app.SensEditField.Value = 0.95423542035814;
            app.EdgeThresholdSliderLabel = uilabel(app.ControlsPanel);
            app.EdgeThresholdSliderLabel.Text = 'EdgeThreshold';
            app.EdgeThresholdSlider = uislider(app.ControlsPanel);
            app.EdgeThresholdSlider.Limits = [0 1];
            app.EdgeThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @EdgeThresholdSliderValueChanging, true);
            app.EdgeThresholdSlider.Value = 0.352482356078236;
            app.EdgeThresLabel = uilabel(app.ControlsPanel);
            app.EdgeThresLabel.Text = 'EdgeThres:';
            app.EdgeThresEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.EdgeThresEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeThresEditFieldValueChanged, true);
            app.EdgeThresEditField.Value = 0.352482356078236;
            app.RefinedImageButton = uibutton(app.ControlsPanel, 'push');
            app.RefinedImageButton.ButtonPushedFcn = createCallbackFcn(app, @RefinedImageButtonPushed, true);
            app.RefinedImageButton.Text = 'Refined Image';
            app.DropDown = uidropdown(app.ControlsPanel);
            app.DropDown.Items = {'Black Circles', 'White Circles'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Black Circles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end